classdef Clawmachinecontrol < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ResetButton               matlab.ui.control.Button
        SubmitButton              matlab.ui.control.Button
        ResolutionEditField       matlab.ui.control.NumericEditField
        ResolutionEditFieldLabel  matlab.ui.control.Label
        IncrementEditField        matlab.ui.control.NumericEditField
        IncrementEditFieldLabel   matlab.ui.control.Label
        GRABButton                matlab.ui.control.Button
        YButton_2                 matlab.ui.control.Button
        YButton                   matlab.ui.control.Button
        XButton_2                 matlab.ui.control.Button
        XButton                   matlab.ui.control.Button
        DisconnectButton          matlab.ui.control.Button
        ConnectButton             matlab.ui.control.Button
        Image                     matlab.ui.control.Image
    end

    
    properties (Access = private)
        %May need to change if different computer
            lib_name = 'dxl_x64_c';
            
            ADDR_PRO_TORQUE_ENABLE       = 64;           % Control table address is different in Dynamixel model
            ADDR_PRO_GOAL_POSITION       = 116; 
            ADDR_PRO_PRESENT_POSITION    = 132; 
            ADDR_PRO_OPERATING_MODE      = 11;
            ADDR_PRO_PROFILE_VELOCITY = 112;
            ADDR_PRO_PROFILE_ACCELERATION = 108;
             
            % Protocol version
            PROTOCOL_VERSION            = 2.0;          % See which protocol version is used in the Dynamixel
             
            % Default setting
            DXL_ID1                     = 11; 
            DXL_ID2                     = 12; 
            DXL_ID3                     = 13;
            DXL_ID4                     = 14;
            DXL_ID5                     = 15;% Dynamixel ID: 1
            BAUDRATE                    = 115200;
            DEVICENAME                  = 'COM11';       % Check which port is being used on your controller
                                                        % ex) Windows: 'COM1'   Linux: '/dev/ttyUSB0' Mac: '/dev/tty.usbserial-*'
            TORQUE_ENABLE               = 1;            % Value for enabling the torque
            TORQUE_DISABLE              = 0;            % Value for disabling the torque
            DXL_MINIMUM_POSITION_VALUE  = -150000;      % Dynamixel will rotate between this value
            DXL_MAXIMUM_POSITION_VALUE  = 150000;       % and this value (note that the Dynamixel would not move when the position value is out of movable range. Check e-manual about the range of the Dynamixel you use.)
            DXL_MOVING_STATUS_THRESHOLD = 20;           % Dynamixel moving status threshold
             
            ESC_CHARACTER               = 'e';          % Key for escaping loop
             
            COMM_SUCCESS                = 0;            % Communication Success result value
            COMM_TX_FAIL                = -1001;        % Communication Tx Failed
            port_num;
            index = 1;
            dxl_comm_result = -1001;           % Communication result
            dxl_error = 0;                              % Dynamixel error
            dxl_present_position = 0;                   % Present position
             
            % ----- SET MOTION LIMITS ----------- %
            ADDR_GOAL_CURRENT            = 102;   % 2BYTE
            ADDR_MAX_POS = 48;
            ADDR_MIN_POS = 52;
            DXL_ID1_MIN_POS = 0000;  % Min and max for DXL_ID1
            DXL_ID1_MAX_POS = 4000;
            DXL_ID2_MIN_POS = 0750;  % Min and max for DXL_ID2
            DXL_ID2_MAX_POS = 3310;
            DXL_ID3_MIN_POS = 0690;  % Min and max for DXL_ID3
            DXL_ID3_MAX_POS = 3050;
            DXL_ID4_MIN_POS = 0837;  % Min and max for DXL_ID4
            DXL_ID4_MAX_POS = 3463;
            DXL_ID5_MIN_POS = 3530;  % Min and max for DXL_ID5
            DXL_ID5_MAX_POS = 6714;
            
            % Theta limits
            THETA1_MIN = -180;
            THETA1_MAX =  180;
            THETA3_MIN = -193;
            THETA3_MAX =   32;
            THETA4_MIN =  -39;
            THETA4_MAX =  169;
            THETA5_MIN = -106;
            THETA5_MAX =  125;
            increment;% = 10;
%             angleincrement = 4;
            notfound;
            warnings;
            resolution;% = 10;
    end
    
    methods (Access = public)
        
        function [position1, position2, position3, position4] = IK(app, x_desired, y_desired, z_desired, angle_desired)
            a3 = 130;
            a4 = 124;
            a5 = 126;
            d2 = 77;
            
            r_desired = sqrt(y_desired.^2 + x_desired.^2);
            [~,icol] = size(x_desired);
            
            theta1 = zeros(1, icol);
            theta3 = zeros(1, icol);
            theta4 = zeros(1, icol);
            theta5 = zeros(1, icol);
            
            for i = 1:icol
                theta1(i) = atan2d(y_desired(i), x_desired(i));
            
                r3 = r_desired(i) - a5*cosd(angle_desired(i));
                z3 = z_desired(i) + a5*sind(angle_desired(i)) - d2;
                h3 = sqrt(r3^2 + z3^2);
                
                if (a3+a4) >= h3
                    %angle a and B
                    a = acosd((a3^2 + a4^2 - h3^2 )/(2*a3*a4));
                    B = acosd((a3^2 + h3^2 - a4^2 )/(2*a3*h3));
            
                    %joint angles elbow-down
                    J1a = -(atan2d(z3,r3)+B);
                    J2a = 180-a;
                    J3a = angle_desired(i) - J1a -J2a;
                
                    %joint angles elbow-up
                    J1b = -(atan2d(z3,r3)-B);
                    J2b = a-180;
                    J3b = angle_desired(i) - J1b - J2b;
            
                    if (app.THETA3_MIN < J1b) && (J1b< app.THETA3_MAX) && (app.THETA4_MIN < J2b) && (J2b< app.THETA4_MAX) &&(app.THETA5_MIN < J3b) && (J3b< app.THETA5_MAX) 
                        theta3(i) = 1*J1b;
                        theta4(i) = 1*J2b;
                        theta5(i) = 1*J3b;
                    else
                        theta3(i) = 1*J1a;
                        theta4(i) = 1*J2a;
                        theta5(i) = 1*J3a;
                    end
                    
                else
                    disp("out of bound");
                    if i ~=1
                       theta3(i) = theta3(i - 1);
                       theta4(i) = theta4(i - 1);
                       theta5(i) = theta5(i - 1);
                    end
                end
            end
            thetafix = asin(0.024/0.13)/pi*180;
            position1 = theta1./180 .* 2048 + 2048;
            position2 = (theta3 - thetafix + 90)./180 .* 2048 + 2048;
            position3 = (theta4+ thetafix - 90)./180 .* 2048 + 2048;
            position4 = theta5./180 .* 2048 + 2048;
        end
       
        
        function [x, y, z, angle] = currentangles(app)
            position1 = read4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID1, app.ADDR_PRO_PRESENT_POSITION);
            position3 = read4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID2, app.ADDR_PRO_PRESENT_POSITION);
            position4 = read4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID3, app.ADDR_PRO_PRESENT_POSITION);
            position5 = read4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID4, app.ADDR_PRO_PRESENT_POSITION);
            thetafix = asin(0.024/0.13)/pi*180;
            theta1 = (position1 - 2048)/2048*180;
            theta3 = (position3 - 2048)/2048*180 - 90 + thetafix;
            theta4 = (position4 - 2048)/2048*180 + 90 - thetafix;
            theta5 = (position5 - 2048)/2048*180;
            r = 130*cosd(theta3)+124*cosd(theta3+theta4)+126*cosd(theta3+theta4+theta5);
            x = r*cosd(theta1);
            y = r*sind(theta1);
            z = 77-130*sind(theta3)-124*sind(theta3+theta4)-126*sind(theta3+theta4+theta5);
            angle = theta3+theta4+theta5;
        end

        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConnectButton
        function ConnectButtonPushed2(app, event)
            % Load Libraries
            if ~libisloaded(app.lib_name)
                [app.notfound, app.warnings] = loadlibrary(app.lib_name, 'dynamixel_sdk.h', 'addheader', 'port_handler.h', 'addheader', 'packet_handler.h');
            end
            app.port_num = portHandler(app.DEVICENAME);
            packetHandler();    
            % Open port
            if (openPort(app.port_num))
                fprintf('Port Open\n');
            else
                unloadlibrary(app.lib_name);
                fprintf('Failed to open the port\n');
                input('Press any key to terminate...\n');
                return;
            end

            if (setBaudRate(app.port_num, app.BAUDRATE))
                fprintf('Baudrate Set\n');
            else
                unloadlibrary(app.lib_name);
                fprintf('Failed to change the baudrate!\n');
                input('Press any key to terminate...\n');
                return;
            end
            app.dxl_comm_result = getLastTxRxResult(app.port_num, app.PROTOCOL_VERSION);
            app.dxl_error = getLastRxPacketError(app.port_num, app.PROTOCOL_VERSION);
            if app.dxl_comm_result ~= app.COMM_SUCCESS
                fprintf('debug');
                fprintf('%s\n', getTxRxResult(app.PROTOCOL_VERSION, app.dxl_comm_result));
            elseif app.dxl_error ~= 0
                fprintf('%s\n', getRxPacketError(app.PROTOCOL_VERSION, app.dxl_error));
            else
                fprintf('Dynamixel has been successfully connected \n');
            end 
            % Set max position limit
            write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID1, app.ADDR_MAX_POS, app.DXL_ID1_MAX_POS);
            write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID2, app.ADDR_MAX_POS, app.DXL_ID2_MAX_POS);
            write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID3, app.ADDR_MAX_POS, app.DXL_ID3_MAX_POS);
            write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID4, app.ADDR_MAX_POS, app.DXL_ID4_MAX_POS);
            write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID5, app.ADDR_MAX_POS, app.DXL_ID5_MAX_POS);
            % Set min position limit
            write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID1, app.ADDR_MIN_POS, app.DXL_ID1_MIN_POS);
            write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID2, app.ADDR_MIN_POS, app.DXL_ID2_MIN_POS);
            write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID3, app.ADDR_MIN_POS, app.DXL_ID3_MIN_POS);
            write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID4, app.ADDR_MIN_POS, app.DXL_ID4_MIN_POS);
            write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID5, app.ADDR_MIN_POS, app.DXL_ID5_MIN_POS);
            
             
            % Put actuator into Position Control Mode
            write1ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID1, app.ADDR_PRO_OPERATING_MODE, 3);
            write1ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID2, app.ADDR_PRO_OPERATING_MODE, 3);
            write1ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID3, app.ADDR_PRO_OPERATING_MODE, 3);
            write1ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID4, app.ADDR_PRO_OPERATING_MODE, 3);
            write1ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID5, app.ADDR_PRO_OPERATING_MODE, 5);
            write2ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID5, app.ADDR_GOAL_CURRENT, 130);
             
            write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID1, app.ADDR_PRO_PROFILE_VELOCITY, 300);
            write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID2, app.ADDR_PRO_PROFILE_VELOCITY, 300);
            write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID3, app.ADDR_PRO_PROFILE_VELOCITY, 300);
            write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID4, app.ADDR_PRO_PROFILE_VELOCITY, 300);
            write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID5, app.ADDR_PRO_PROFILE_VELOCITY, 300);
            
            write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID1, app.ADDR_PRO_PROFILE_ACCELERATION, 100);
            write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID2, app.ADDR_PRO_PROFILE_ACCELERATION, 100);
            write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID3, app.ADDR_PRO_PROFILE_ACCELERATION, 100);
            write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID4, app.ADDR_PRO_PROFILE_ACCELERATION, 100);
            write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID5, app.ADDR_PRO_PROFILE_ACCELERATION, 100);
            % Enable Dynamixel Torque
            write1ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID1, app.ADDR_PRO_TORQUE_ENABLE, 1);
            write1ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID2, app.ADDR_PRO_TORQUE_ENABLE, 1);
            write1ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID3, app.ADDR_PRO_TORQUE_ENABLE, 1);
            write1ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID4, app.ADDR_PRO_TORQUE_ENABLE, 1);
            write1ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID5, app.ADDR_PRO_TORQUE_ENABLE, 1);
            app.dxl_comm_result = getLastTxRxResult(app.port_num, app.PROTOCOL_VERSION);
            app.dxl_error = getLastRxPacketError(app.port_num, app.PROTOCOL_VERSION);
            open = 3500;
            write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID5, app.ADDR_PRO_GOAL_POSITION, open);
            [x_from, y_from, z_from, angle_from] = currentangles(app);
          x_to = 109;
          y_to = 0;
          z_to = 150;
          angle_to = 90;
          xTraj = zeros(1, app.resolution);
          yTraj = zeros(1, app.resolution);
          zTraj = zeros(1, app.resolution);
          angleTraj = zeros(1, app.resolution);
    
          for i = 1:app.resolution
            xTraj(i) = x_from + 3/app.resolution^2*(x_to-x_from)*i^2 - 2/app.resolution^3*(x_to-x_from)*i^3;
            yTraj(i) = y_from + 3/app.resolution^2*(y_to-y_from)*i^2 - 2/app.resolution^3*(y_to-y_from)*i^3;
            zTraj(i) = z_from + 3/app.resolution^2*(z_to-z_from)*i^2 - 2/app.resolution^3*(z_to-z_from)*i^3;
            angleTraj(i)  = angle_from + 3/app.resolution^2*(angle_to-angle_from)*i^2 - 2/app.resolution^3*(angle_to-angle_from)*i^3;
          end
%           xTraj = linspace(x_from, x_to, app.resolution);
%           yTraj = linspace(y_from, y_to, app.resolution);
%           zTraj = linspace(z_from, z_to, app.resolution);
%           angleTraj = linspace(angle_from, angle_to, app.resolution);
          [position1, position2, position3, position4] = IK(app,xTraj, yTraj, zTraj, angleTraj);
          for i = 1:length(xTraj)
          write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID1, app.ADDR_PRO_GOAL_POSITION, position1(i));
          write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID2, app.ADDR_PRO_GOAL_POSITION, position2(i));
          write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID3, app.ADDR_PRO_GOAL_POSITION, position3(i));
          write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID4, app.ADDR_PRO_GOAL_POSITION, position4(i));
          end
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed2(app, event)
          [x_from, y_from, z_from, angle_from] = currentangles(app);
          x_to = 109;
          y_to = 0;
          z_to = 90;
          angle_to = 0;
          xTraj = zeros(1, app.resolution);
          yTraj = zeros(1, app.resolution);
          zTraj = zeros(1, app.resolution);
          angleTraj = zeros(1, app.resolution);
    
          for i = 1:app.resolution
            xTraj(i) = x_from + 3/app.resolution^2*(x_to-x_from)*i^2 - 2/app.resolution^3*(x_to-x_from)*i^3;
            yTraj(i) = y_from + 3/app.resolution^2*(y_to-y_from)*i^2 - 2/app.resolution^3*(y_to-y_from)*i^3;
            zTraj(i) = z_from + 3/app.resolution^2*(z_to-z_from)*i^2 - 2/app.resolution^3*(z_to-z_from)*i^3;
            angleTraj(i)  = angle_from + 3/app.resolution^2*(angle_to-angle_from)*i^2 - 2/app.resolution^3*(angle_to-angle_from)*i^3;
          end
%           xTraj = linspace(x_from, x_to, app.resolution);
%           yTraj = linspace(y_from, y_to, app.resolution);
%           zTraj = linspace(z_from, z_to, app.resolution);
%           angleTraj = linspace(angle_from, angle_to, app.resolution);
          [position1, position2, position3, position4] = IK(app,xTraj, yTraj, zTraj, angleTraj);
          for i = 1:length(xTraj)
          write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID1, app.ADDR_PRO_GOAL_POSITION, position1(i));
          write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID2, app.ADDR_PRO_GOAL_POSITION, position2(i));
          write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID3, app.ADDR_PRO_GOAL_POSITION, position3(i));
          write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID4, app.ADDR_PRO_GOAL_POSITION, position4(i));
          end
          
          % Disable Dynamixel Torque
            write1ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID1, app.ADDR_PRO_TORQUE_ENABLE, 0);
            write1ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID2, app.ADDR_PRO_TORQUE_ENABLE, 0);
            write1ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID3, app.ADDR_PRO_TORQUE_ENABLE, 0);
            write1ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID4, app.ADDR_PRO_TORQUE_ENABLE, 0);
            write1ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID5, app.ADDR_PRO_TORQUE_ENABLE, 0);
            
            % Close port
            closePort(app.port_num);
            fprintf('Port Closed \n');
             
            % Unload Library
            unloadlibrary(app.lib_name);
             
        end

        % Button pushed function: GRABButton
        function Grab(app, event)
          xoutrange = -5:180;
           xout = randsample(xoutrange,1);
           if xout < 70
               disp("SLAUGHTER TIME >:)");
               msgbox('SLAUGHTER TIME >:)', 'Message');
           elseif xout > 115
                   disp("BE FREE MY DARLING :)");
                   msgbox('BE FREE MY DARLING :)', 'Message');
           else 
               disp("You could go either way");
               msgbox('meh i cannot decide so you can go in the middle', 'Message');
           end
            
              
            [x_from, y_from, z_from, angle_from] = currentangles(app);
          x_to = x_from;
          y_to = y_from;
          z_to = 60;
          angle_to = angle_from;
          xTraj = zeros(1, app.resolution);
          yTraj = zeros(1, app.resolution);
          zTraj = zeros(1, app.resolution);
          angleTraj = zeros(1, app.resolution);
    
          for i = 1:app.resolution
            xTraj(i) = x_from + 3/app.resolution^2*(x_to-x_from)*i^2 - 2/app.resolution^3*(x_to-x_from)*i^3;
            yTraj(i) = y_from + 3/app.resolution^2*(y_to-y_from)*i^2 - 2/app.resolution^3*(y_to-y_from)*i^3;
            zTraj(i) = z_from + 3/app.resolution^2*(z_to-z_from)*i^2 - 2/app.resolution^3*(z_to-z_from)*i^3;
            angleTraj(i)  = angle_from + 3/app.resolution^2*(angle_to-angle_from)*i^2 - 2/app.resolution^3*(angle_to-angle_from)*i^3;
          end
%           xTraj = linspace(x_from, x_to, app.resolution);
%           yTraj = linspace(y_from, y_to, app.resolution);
%           zTraj = linspace(z_from, z_to, app.resolution);
%           angleTraj = linspace(angle_from, angle_to, app.resolution);
          [position1, position2, position3, position4] = IK(app,xTraj, yTraj, zTraj, angleTraj);
          for i = 1:length(xTraj)
          write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID1, app.ADDR_PRO_GOAL_POSITION, position1(i));
          write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID2, app.ADDR_PRO_GOAL_POSITION, position2(i));
          write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID3, app.ADDR_PRO_GOAL_POSITION, position3(i));
          write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID4, app.ADDR_PRO_GOAL_POSITION, position4(i));
          end
            closed = 1450;
            open = 2800;
            write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID5, app.ADDR_PRO_GOAL_POSITION, closed); 

          [x_from, y_from, z_from, angle_from] = currentangles(app);
          x_to = x_from;
          y_to = y_from;
          z_to = 150;
          angle_to = angle_from;
          xTraj = zeros(1, app.resolution);
          yTraj = zeros(1, app.resolution);
          zTraj = zeros(1, app.resolution);
          angleTraj = zeros(1, app.resolution);
    
          for i = 1:app.resolution
            xTraj(i) = x_from + 3/app.resolution^2*(x_to-x_from)*i^2 - 2/app.resolution^3*(x_to-x_from)*i^3;
            yTraj(i) = y_from + 3/app.resolution^2*(y_to-y_from)*i^2 - 2/app.resolution^3*(y_to-y_from)*i^3;
            zTraj(i) = z_from + 3/app.resolution^2*(z_to-z_from)*i^2 - 2/app.resolution^3*(z_to-z_from)*i^3;
            angleTraj(i)  = angle_from + 3/app.resolution^2*(angle_to-angle_from)*i^2 - 2/app.resolution^3*(angle_to-angle_from)*i^3;
          end
%           xTraj = linspace(x_from, x_to, app.resolution);
%           yTraj = linspace(y_from, y_to, app.resolution);
%           zTraj = linspace(z_from, z_to, app.resolution);
%           angleTraj = linspace(angle_from, angle_to, app.resolution);
          [position1, position2, position3, position4] = IK(app,xTraj, yTraj, zTraj, angleTraj);
          for i = 1:length(xTraj)
          write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID1, app.ADDR_PRO_GOAL_POSITION, position1(i));
          write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID2, app.ADDR_PRO_GOAL_POSITION, position2(i));
          write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID3, app.ADDR_PRO_GOAL_POSITION, position3(i));
          write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID4, app.ADDR_PRO_GOAL_POSITION, position4(i));
          end
          [x_from, y_from, z_from, angle_from] = currentangles(app);
          x_to = xout;
          y_to = -120;
          z_to = 150;
          angle_to = 90;
          xTraj = zeros(1, app.resolution);
          yTraj = zeros(1, app.resolution);
          zTraj = zeros(1, app.resolution);
          angleTraj = zeros(1, app.resolution);
    
          for i = 1:app.resolution
            xTraj(i) = x_from + 3/app.resolution^2*(x_to-x_from)*i^2 - 2/app.resolution^3*(x_to-x_from)*i^3;
            yTraj(i) = y_from + 3/app.resolution^2*(y_to-y_from)*i^2 - 2/app.resolution^3*(y_to-y_from)*i^3;
            zTraj(i) = z_from + 3/app.resolution^2*(z_to-z_from)*i^2 - 2/app.resolution^3*(z_to-z_from)*i^3;
            angleTraj(i)  = angle_from + 3/app.resolution^2*(angle_to-angle_from)*i^2 - 2/app.resolution^3*(angle_to-angle_from)*i^3;
          end
%           xTraj = linspace(x_from, x_to, app.resolution);
%           yTraj = linspace(y_from, y_to, app.resolution);
%           zTraj = linspace(z_from, z_to, app.resolution);
%           angleTraj = linspace(angle_from, angle_to, app.resolution);
          [position1, position2, position3, position4] = IK(app,xTraj, yTraj, zTraj, angleTraj);
          for i = 1:length(xTraj)
          write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID1, app.ADDR_PRO_GOAL_POSITION, position1(i));
          write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID2, app.ADDR_PRO_GOAL_POSITION, position2(i));
          write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID3, app.ADDR_PRO_GOAL_POSITION, position3(i));
          write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID4, app.ADDR_PRO_GOAL_POSITION, position4(i));
          end
            [x_from, y_from, z_from, angle_from] = currentangles(app);
            x_to = xout;
          y_to = -120;
          z_to = 60;
          angle_to = angle_from;
          xTraj = zeros(1, app.resolution);
          yTraj = zeros(1, app.resolution);
          zTraj = zeros(1, app.resolution);
          angleTraj = zeros(1, app.resolution);
    
          for i = 1:app.resolution
            xTraj(i) = x_from + 3/app.resolution^2*(x_to-x_from)*i^2 - 2/app.resolution^3*(x_to-x_from)*i^3;
            yTraj(i) = y_from + 3/app.resolution^2*(y_to-y_from)*i^2 - 2/app.resolution^3*(y_to-y_from)*i^3;
            zTraj(i) = z_from + 3/app.resolution^2*(z_to-z_from)*i^2 - 2/app.resolution^3*(z_to-z_from)*i^3;
            angleTraj(i)  = angle_from + 3/app.resolution^2*(angle_to-angle_from)*i^2 - 2/app.resolution^3*(angle_to-angle_from)*i^3;
          end
%           xTraj = linspace(x_from, x_to, app.resolution);
%           yTraj = linspace(y_from, y_to, app.resolution);
%           zTraj = linspace(z_from, z_to, app.resolution);
%           angleTraj = linspace(angle_from, angle_to, app.resolution);
          [position1, position2, position3, position4] = IK(app,xTraj, yTraj, zTraj, angleTraj);
          for i = 1:length(xTraj)
          write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID1, app.ADDR_PRO_GOAL_POSITION, position1(i));
          write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID2, app.ADDR_PRO_GOAL_POSITION, position2(i));
          write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID3, app.ADDR_PRO_GOAL_POSITION, position3(i));
          write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID4, app.ADDR_PRO_GOAL_POSITION, position4(i));
          end

            write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID5, app.ADDR_PRO_GOAL_POSITION, open); 
            pause(1)

            [x_from, y_from, z_from, angle_from] = currentangles(app);
            x_to = xout;
          y_to = -120;
          z_to = 150;
          angle_to = angle_from;
          xTraj = zeros(1, app.resolution);
          yTraj = zeros(1, app.resolution);
          zTraj = zeros(1, app.resolution);
          angleTraj = zeros(1, app.resolution);
    
          for i = 1:app.resolution
            xTraj(i) = x_from + 3/app.resolution^2*(x_to-x_from)*i^2 - 2/app.resolution^3*(x_to-x_from)*i^3;
            yTraj(i) = y_from + 3/app.resolution^2*(y_to-y_from)*i^2 - 2/app.resolution^3*(y_to-y_from)*i^3;
            zTraj(i) = z_from + 3/app.resolution^2*(z_to-z_from)*i^2 - 2/app.resolution^3*(z_to-z_from)*i^3;
            angleTraj(i)  = angle_from + 3/app.resolution^2*(angle_to-angle_from)*i^2 - 2/app.resolution^3*(angle_to-angle_from)*i^3;
          end
%           xTraj = linspace(x_from, x_to, app.resolution);
%           yTraj = linspace(y_from, y_to, app.resolution);
%           zTraj = linspace(z_from, z_to, app.resolution);
%           angleTraj = linspace(angle_from, angle_to, app.resolution);
          [position1, position2, position3, position4] = IK(app,xTraj, yTraj, zTraj, angleTraj);
          for i = 1:length(xTraj)
          write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID1, app.ADDR_PRO_GOAL_POSITION, position1(i));
          write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID2, app.ADDR_PRO_GOAL_POSITION, position2(i));
          write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID3, app.ADDR_PRO_GOAL_POSITION, position3(i));
          write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID4, app.ADDR_PRO_GOAL_POSITION, position4(i));
          end
          

            [x_from, y_from, z_from, angle_from] = currentangles(app);
          x_to = 109;
          y_to = 0;
          z_to = 150;
          angle_to = 90;
          xTraj = zeros(1, app.resolution);
          yTraj = zeros(1, app.resolution);
          zTraj = zeros(1, app.resolution);
          angleTraj = zeros(1, app.resolution);
    
          for i = 1:app.resolution
            xTraj(i) = x_from + 3/app.resolution^2*(x_to-x_from)*i^2 - 2/app.resolution^3*(x_to-x_from)*i^3;
            yTraj(i) = y_from + 3/app.resolution^2*(y_to-y_from)*i^2 - 2/app.resolution^3*(y_to-y_from)*i^3;
            zTraj(i) = z_from + 3/app.resolution^2*(z_to-z_from)*i^2 - 2/app.resolution^3*(z_to-z_from)*i^3;
            angleTraj(i)  = angle_from + 3/app.resolution^2*(angle_to-angle_from)*i^2 - 2/app.resolution^3*(angle_to-angle_from)*i^3;
          end
%           xTraj = linspace(x_from, x_to, app.resolution);
%           yTraj = linspace(y_from, y_to, app.resolution);
%           zTraj = linspace(z_from, z_to, app.resolution);
%           angleTraj = linspace(angle_from, angle_to, app.resolution);
          [position1, position2, position3, position4] = IK(app,xTraj, yTraj, zTraj, angleTraj);
          for i = 1:length(xTraj)
          write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID1, app.ADDR_PRO_GOAL_POSITION, position1(i));
          write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID2, app.ADDR_PRO_GOAL_POSITION, position2(i));
          write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID3, app.ADDR_PRO_GOAL_POSITION, position3(i));
          write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID4, app.ADDR_PRO_GOAL_POSITION, position4(i));
          end
          write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID5, app.ADDR_PRO_GOAL_POSITION, open); 
            
        end

        % Button pushed function: XButton
        function XButtonPushed(app, event)
          [x_from, y_from, z_from, angle_from] = currentangles(app);
          x_to = x_from + app.increment;
          y_to = y_from;
          z_to = 150;
          angle_to = 90;
          xTraj = zeros(1, app.resolution);
          yTraj = zeros(1, app.resolution);
          zTraj = zeros(1, app.resolution);
          angleTraj = zeros(1, app.resolution);
    
          for i = 1:app.resolution
            xTraj(i) = x_from + 3/app.resolution^2*(x_to-x_from)*i^2 - 2/app.resolution^3*(x_to-x_from)*i^3;
            yTraj(i) = y_from + 3/app.resolution^2*(y_to-y_from)*i^2 - 2/app.resolution^3*(y_to-y_from)*i^3;
            zTraj(i) = z_from + 3/app.resolution^2*(z_to-z_from)*i^2 - 2/app.resolution^3*(z_to-z_from)*i^3;
            angleTraj(i)  = angle_from + 3/app.resolution^2*(angle_to-angle_from)*i^2 - 2/app.resolution^3*(angle_to-angle_from)*i^3;
          end
%             disp(x_from)
%             disp(y_from)
%             disp(z_from)
%             disp(angle_from)
%           xTraj = linspace(x_from, x_to, app.resolution);
%           yTraj = linspace(y_from, y_to, app.resolution);
%           zTraj = linspace(z_from, z_to, app.resolution);
%           angleTraj = linspace(angle_from, angle_to, app.resolution);
          [position1, position2, position3, position4] = IK(app,xTraj, yTraj, zTraj, angleTraj);
          for i = 1:length(xTraj)
          write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID1, app.ADDR_PRO_GOAL_POSITION, position1(i));
          write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID2, app.ADDR_PRO_GOAL_POSITION, position2(i));
          write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID3, app.ADDR_PRO_GOAL_POSITION, position3(i));
          write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID4, app.ADDR_PRO_GOAL_POSITION, position4(i));
          end
        end

        % Button pushed function: XButton_2
        function XButton_2Pushed(app, event)
          [x_from, y_from, z_from, angle_from] = currentangles(app);
          x_to = x_from - app.increment;
          y_to = y_from;
          z_to = 150;
          angle_to = 90;
          xTraj = zeros(1, app.resolution);
          yTraj = zeros(1, app.resolution);
          zTraj = zeros(1, app.resolution);
          angleTraj = zeros(1, app.resolution);
    
          for i = 1:app.resolution
            xTraj(i) = x_from + 3/app.resolution^2*(x_to-x_from)*i^2 - 2/app.resolution^3*(x_to-x_from)*i^3;
            yTraj(i) = y_from + 3/app.resolution^2*(y_to-y_from)*i^2 - 2/app.resolution^3*(y_to-y_from)*i^3;
            zTraj(i) = z_from + 3/app.resolution^2*(z_to-z_from)*i^2 - 2/app.resolution^3*(z_to-z_from)*i^3;
            angleTraj(i)  = angle_from + 3/app.resolution^2*(angle_to-angle_from)*i^2 - 2/app.resolution^3*(angle_to-angle_from)*i^3;
          end
%           xTraj = linspace(x_from, x_to, app.resolution);
%           yTraj = linspace(y_from, y_to, app.resolution);
%           zTraj = linspace(z_from, z_to, app.resolution);
%           angleTraj = linspace(angle_from, angle_to, app.resolution);
          [position1, position2, position3, position4] = IK(app,xTraj, yTraj, zTraj, angleTraj);
          for i = 1:length(xTraj)
          write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID1, app.ADDR_PRO_GOAL_POSITION, position1(i));
          write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID2, app.ADDR_PRO_GOAL_POSITION, position2(i));
          write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID3, app.ADDR_PRO_GOAL_POSITION, position3(i));
          write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID4, app.ADDR_PRO_GOAL_POSITION, position4(i));
          end
        end

        % Button pushed function: YButton
        function YButtonPushed(app, event)
          [x_from, y_from, z_from, angle_from] = currentangles(app);
          x_to = x_from;
          y_to = y_from + app.increment;
          z_to = 150;
          angle_to = 90;
          xTraj = zeros(1, app.resolution);
          yTraj = zeros(1, app.resolution);
          zTraj = zeros(1, app.resolution);
          angleTraj = zeros(1, app.resolution);
    
          for i = 1:app.resolution
            xTraj(i) = x_from + 3/app.resolution^2*(x_to-x_from)*i^2 - 2/app.resolution^3*(x_to-x_from)*i^3;
            yTraj(i) = y_from + 3/app.resolution^2*(y_to-y_from)*i^2 - 2/app.resolution^3*(y_to-y_from)*i^3;
            zTraj(i) = z_from + 3/app.resolution^2*(z_to-z_from)*i^2 - 2/app.resolution^3*(z_to-z_from)*i^3;
            angleTraj(i)  = angle_from + 3/app.resolution^2*(angle_to-angle_from)*i^2 - 2/app.resolution^3*(angle_to-angle_from)*i^3;
          end
%           xTraj = linspace(x_from, x_to, app.resolution);
%           yTraj = linspace(y_from, y_to, app.resolution);
%           zTraj = linspace(z_from, z_to, app.resolution);
%           angleTraj = linspace(angle_from, angle_to, app.resolution);
          [position1, position2, position3, position4] = IK(app,xTraj, yTraj, zTraj, angleTraj);
          for i = 1:length(xTraj)
          write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID1, app.ADDR_PRO_GOAL_POSITION, position1(i));
          write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID2, app.ADDR_PRO_GOAL_POSITION, position2(i));
          write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID3, app.ADDR_PRO_GOAL_POSITION, position3(i));
          write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID4, app.ADDR_PRO_GOAL_POSITION, position4(i));
          end
        end

        % Button pushed function: YButton_2
        function YButton_2Pushed(app, event)
           [x_from, y_from, z_from, angle_from] = currentangles(app);
          x_to = x_from;
          y_to = y_from - app.increment;
          z_to = z_from;
          angle_to = angle_from;
          xTraj = zeros(1, app.resolution);
          yTraj = zeros(1, app.resolution);
          zTraj = zeros(1, app.resolution);
          angleTraj = zeros(1, app.resolution);
    
          for i = 1:app.resolution
            xTraj(i) = x_from + 3/app.resolution^2*(x_to-x_from)*i^2 - 2/app.resolution^3*(x_to-x_from)*i^3;
            yTraj(i) = y_from + 3/app.resolution^2*(y_to-y_from)*i^2 - 2/app.resolution^3*(y_to-y_from)*i^3;
            zTraj(i) = z_from + 3/app.resolution^2*(z_to-z_from)*i^2 - 2/app.resolution^3*(z_to-z_from)*i^3;
            angleTraj(i)  = angle_from + 3/app.resolution^2*(angle_to-angle_from)*i^2 - 2/app.resolution^3*(angle_to-angle_from)*i^3;
          end
%           xTraj = linspace(x_from, x_to, app.resolution);
%           yTraj = linspace(y_from, y_to, app.resolution);
%           zTraj = linspace(z_from, z_to, app.resolution);
%           angleTraj = linspace(angle_from, angle_to, app.resolution);
          [position1, position2, position3, position4] = IK(app,xTraj, yTraj, zTraj, angleTraj);
          for i = 1:length(xTraj)
          write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID1, app.ADDR_PRO_GOAL_POSITION, position1(i));
          write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID2, app.ADDR_PRO_GOAL_POSITION, position2(i));
          write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID3, app.ADDR_PRO_GOAL_POSITION, position3(i));
          write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID4, app.ADDR_PRO_GOAL_POSITION, position4(i));
          end
        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            app.increment = app.IncrementEditField.Value;
            app.resolution = app.ResolutionEditField.Value;
            disp(app.increment)
            disp(app.resolution)
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            open = 3500;
            write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID5, app.ADDR_PRO_GOAL_POSITION, open);
            [x_from, y_from, z_from, angle_from] = currentangles(app);
          x_to = 109;
          y_to = 0;
          z_to = 150;
          angle_to = 90;
          xTraj = zeros(1, app.resolution);
          yTraj = zeros(1, app.resolution);
          zTraj = zeros(1, app.resolution);
          angleTraj = zeros(1, app.resolution);
    
          for i = 1:app.resolution
            xTraj(i) = x_from + 3/app.resolution^2*(x_to-x_from)*i^2 - 2/app.resolution^3*(x_to-x_from)*i^3;
            yTraj(i) = y_from + 3/app.resolution^2*(y_to-y_from)*i^2 - 2/app.resolution^3*(y_to-y_from)*i^3;
            zTraj(i) = z_from + 3/app.resolution^2*(z_to-z_from)*i^2 - 2/app.resolution^3*(z_to-z_from)*i^3;
            angleTraj(i)  = angle_from + 3/app.resolution^2*(angle_to-angle_from)*i^2 - 2/app.resolution^3*(angle_to-angle_from)*i^3;
          end
%           xTraj = linspace(x_from, x_to, app.resolution);
%           yTraj = linspace(y_from, y_to, app.resolution);
%           zTraj = linspace(z_from, z_to, app.resolution);
%           angleTraj = linspace(angle_from, angle_to, app.resolution);
          [position1, position2, position3, position4] = IK(app,xTraj, yTraj, zTraj, angleTraj);
          for i = 1:length(xTraj)
          write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID1, app.ADDR_PRO_GOAL_POSITION, position1(i));
          write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID2, app.ADDR_PRO_GOAL_POSITION, position2(i));
          write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID3, app.ADDR_PRO_GOAL_POSITION, position3(i));
          write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_ID4, app.ADDR_PRO_GOAL_POSITION, position4(i));
          end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = '9876731-family-of-cartoon-sheep-on-landscape-background.jpg';
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed2, true);
            app.ConnectButton.Text = {'Connect'; ''};
            app.DisconnectButton = uibutton(app.UIFigure, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed2, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.XButton = uibutton(app.UIFigure, 'push');
            app.XButton.ButtonPushedFcn = createCallbackFcn(app, @XButtonPushed, true);
            app.XButton.Text = '+X';
            app.XButton_2 = uibutton(app.UIFigure, 'push');
            app.XButton_2.ButtonPushedFcn = createCallbackFcn(app, @XButton_2Pushed, true);
            app.XButton_2.Text = '-X';
            app.YButton = uibutton(app.UIFigure, 'push');
            app.YButton.ButtonPushedFcn = createCallbackFcn(app, @YButtonPushed, true);
            app.YButton.Text = '+Y';
            app.YButton_2 = uibutton(app.UIFigure, 'push');
            app.YButton_2.ButtonPushedFcn = createCallbackFcn(app, @YButton_2Pushed, true);
            app.YButton_2.Text = '-Y';
            app.GRABButton = uibutton(app.UIFigure, 'push');
            app.GRABButton.ButtonPushedFcn = createCallbackFcn(app, @Grab, true);
            app.GRABButton.Text = 'GRAB';
            app.IncrementEditFieldLabel = uilabel(app.UIFigure);
            app.IncrementEditFieldLabel.Text = 'Increment';
            app.IncrementEditField = uieditfield(app.UIFigure, 'numeric');
            app.IncrementEditField.Value = 10;
            app.ResolutionEditFieldLabel = uilabel(app.UIFigure);
            app.ResolutionEditFieldLabel.Text = 'Resolution';
            app.ResolutionEditField = uieditfield(app.UIFigure, 'numeric');
            app.ResolutionEditField.Value = 50;
            app.SubmitButton = uibutton(app.UIFigure, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Clawmachinecontrol

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end